classdef FunctionConfiguration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Function1Label               matlab.ui.control.Label
        Function2Label               matlab.ui.control.Label
        Function3Label               matlab.ui.control.Label
        Function4Label               matlab.ui.control.Label
        Function5Label               matlab.ui.control.Label
        RplicasEditFieldLabel        matlab.ui.control.Label
        RplicasEditField             matlab.ui.control.NumericEditField
        DimensionesEditFieldLabel    matlab.ui.control.Label
        DimensionesEditField         matlab.ui.control.NumericEditField
        RplicasEditField_2Label      matlab.ui.control.Label
        RplicasEditField_2           matlab.ui.control.NumericEditField
        RplicasEditField_3Label      matlab.ui.control.Label
        RplicasEditField_3           matlab.ui.control.NumericEditField
        RplicasEditField_4Label      matlab.ui.control.Label
        RplicasEditField_4           matlab.ui.control.NumericEditField
        RplicasEditField_5Label      matlab.ui.control.Label
        RplicasEditField_5           matlab.ui.control.NumericEditField
        DimensionesEditField_2Label  matlab.ui.control.Label
        DimensionesEditField_2       matlab.ui.control.NumericEditField
        DimensionesEditField_3Label  matlab.ui.control.Label
        DimensionesEditField_3       matlab.ui.control.NumericEditField
        DimensionesEditField_4Label  matlab.ui.control.Label
        DimensionesEditField_4       matlab.ui.control.NumericEditField
        DimensionesEditField_5Label  matlab.ui.control.Label
        DimensionesEditField_5       matlab.ui.control.NumericEditField
        AceptarButton                matlab.ui.control.Button
    end


    methods (Access = private)
        function ret = getFunctionName(app, str)
            if (str == 'test_function_1.m')
                ret = 'Bent Cigar Function';
            elseif (str == 'test_function_2.m')
                ret = 'Rastrigin’s Function';
            elseif (str == 'test_function_3.m')
                ret = 'SF2 @300';
            elseif (str == 'test_function_4.m')
                ret = 'SF2 @400';
            elseif (str == 'test_function_5.m')
                ret = 'SF2 @500';
            end
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, arrFunctions)
            app.Function1Label.Text = getFunctionName(app, arrFunctions(1));
            app.Function2Label.Text = getFunctionName(app, arrFunctions(2));
            app.Function3Label.Text = getFunctionName(app, arrFunctions(3));
            app.Function4Label.Text = getFunctionName(app, arrFunctions(4));
            app.Function5Label.Text = getFunctionName(app, arrFunctions(5));
        end

        % Button pushed function: AceptarButton
        function AceptarButtonPushed(app, event)
            closereq;
            Graph();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Function1Label = uilabel(app.UIFigure);
            app.Function1Label.Text = 'Function 1';
            app.Function2Label = uilabel(app.UIFigure);
            app.Function2Label.Text = 'Function 2';
            app.Function3Label = uilabel(app.UIFigure);
            app.Function3Label.Text = 'Function 3';
            app.Function4Label = uilabel(app.UIFigure);
            app.Function4Label.Text = 'Function 4';
            app.Function5Label = uilabel(app.UIFigure);
            app.Function5Label.Text = 'Function 5';
            app.RplicasEditFieldLabel = uilabel(app.UIFigure);
            app.RplicasEditFieldLabel.Text = 'Réplicas';
            app.RplicasEditField = uieditfield(app.UIFigure, 'numeric');
            app.DimensionesEditFieldLabel = uilabel(app.UIFigure);
            app.DimensionesEditFieldLabel.Text = 'Dimensiones';
            app.DimensionesEditField = uieditfield(app.UIFigure, 'numeric');
            app.RplicasEditField_2Label = uilabel(app.UIFigure);
            app.RplicasEditField_2Label.Text = 'Réplicas';
            app.RplicasEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.RplicasEditField_3Label = uilabel(app.UIFigure);
            app.RplicasEditField_3Label.Text = 'Réplicas';
            app.RplicasEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.RplicasEditField_4Label = uilabel(app.UIFigure);
            app.RplicasEditField_4Label.Text = 'Réplicas';
            app.RplicasEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.RplicasEditField_5Label = uilabel(app.UIFigure);
            app.RplicasEditField_5Label.Text = 'Réplicas';
            app.RplicasEditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.DimensionesEditField_2Label = uilabel(app.UIFigure);
            app.DimensionesEditField_2Label.Text = 'Dimensiones';
            app.DimensionesEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.DimensionesEditField_3Label = uilabel(app.UIFigure);
            app.DimensionesEditField_3Label.Text = 'Dimensiones';
            app.DimensionesEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.DimensionesEditField_4Label = uilabel(app.UIFigure);
            app.DimensionesEditField_4Label.Text = 'Dimensiones';
            app.DimensionesEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.DimensionesEditField_5Label = uilabel(app.UIFigure);
            app.DimensionesEditField_5Label.Text = 'Dimensiones';
            app.DimensionesEditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.AceptarButton = uibutton(app.UIFigure, 'push');
            app.AceptarButton.ButtonPushedFcn = createCallbackFcn(app, @AceptarButtonPushed, true);
            app.AceptarButton.Text = 'Aceptar';
        end
    end

    methods (Access = public)

        % Construct app
        function app = FunctionConfiguration(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end