classdef Project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        PorHugoSamuelyCarlaLabel  matlab.ui.control.Label
        CheckBox1                 matlab.ui.control.CheckBox
        CheckBox2                 matlab.ui.control.CheckBox
        CheckBox3                 matlab.ui.control.CheckBox
        CheckBox4                 matlab.ui.control.CheckBox
        CheckBox5                 matlab.ui.control.CheckBox
        ElijacincofuncionesacompararLabel  matlab.ui.control.Label
        CheckBox6                 matlab.ui.control.CheckBox
        CheckBox7                 matlab.ui.control.CheckBox
        AceptarButton             matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: AceptarButton
        function AceptarButtonPushed(app, event)
            cnt = 1;
            arrFunctions = ["", "", "", "", ""];
            if (app.CheckBox1.Value)
                arrFunctions(cnt) = 'test_function_1.m';
                cnt = cnt + 1;
            end
            
            if (app.CheckBox2.Value)
                arrFunctions(cnt) = 'test_function_2.m';
                cnt = cnt + 1;
            end
            
            if (app.CheckBox3.Value)
                arrFunctions(cnt) = 'test_function_3.m';
                cnt = cnt + 1;
            end
            
            if (app.CheckBox4.Value)
                arrFunctions(cnt) = 'test_function_4.m';
                cnt = cnt + 1;
            end
            
            if (app.CheckBox5.Value)
                arrFunctions(cnt) = 'test_function_5.m';
                cnt = cnt + 1;
            end
            
            if (app.CheckBox6.Value)
                arrFunctions(cnt) = 'test_function_6.m';
                cnt = cnt + 1;
            end
            
            if (app.CheckBox7.Value)
                arrFunctions(cnt) = 'test_function_7.m';
                cnt = cnt + 1;
            end
            
            closereq;
            FunctionConfiguration(arrFunctions);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.PorHugoSamuelyCarlaLabel = uilabel(app.UIFigure);
            app.PorHugoSamuelyCarlaLabel.Text = 'Por Hugo, Samuel y Carla';
            app.CheckBox1 = uicheckbox(app.UIFigure);
            app.CheckBox2 = uicheckbox(app.UIFigure);
            app.CheckBox2.Text = 'Check Box2';
            app.CheckBox3 = uicheckbox(app.UIFigure);
            app.CheckBox3.Text = 'Check Box3';
            app.CheckBox4 = uicheckbox(app.UIFigure);
            app.CheckBox4.Text = 'Check Box4';
            app.CheckBox5 = uicheckbox(app.UIFigure);
            app.CheckBox5.Text = 'Check Box5';
            app.ElijacincofuncionesacompararLabel = uilabel(app.UIFigure);
            app.ElijacincofuncionesacompararLabel.Text = 'Elija cinco funciones a comparar';
            app.CheckBox6 = uicheckbox(app.UIFigure);
            app.CheckBox6.Text = 'Check Box6';
            app.CheckBox7 = uicheckbox(app.UIFigure);
            app.CheckBox7.Text = 'Check Box7';
            app.AceptarButton = uibutton(app.UIFigure, 'push');
            app.AceptarButton.ButtonPushedFcn = createCallbackFcn(app, @AceptarButtonPushed, true);
            app.AceptarButton.Text = 'Aceptar';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Project

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end